classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField6                     matlab.ui.control.NumericEditField
        EditField5                     matlab.ui.control.NumericEditField
        EditField4                     matlab.ui.control.NumericEditField
        EditField3                     matlab.ui.control.NumericEditField
        EditField2                     matlab.ui.control.NumericEditField
        EditField1                     matlab.ui.control.NumericEditField
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        EndDemonstrationButton         matlab.ui.control.Button
        RobotDropDown                  matlab.ui.control.DropDown
        RobotDropDownLabel             matlab.ui.control.Label
        Joint6Slider                   matlab.ui.control.Slider
        Joint6SliderLabel              matlab.ui.control.Label
        Joint5Slider                   matlab.ui.control.Slider
        Joint5Slider_2Label            matlab.ui.control.Label
        Joint4Slider                   matlab.ui.control.Slider
        Joint4Slider_2Label            matlab.ui.control.Label
        Joint3Slider                   matlab.ui.control.Slider
        Joint3Slider_2Label            matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2Slider_2Label            matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1Slider_2Label            matlab.ui.control.Label
        StartTeachButton               matlab.ui.control.Button
        StartBlackjackButton           matlab.ui.control.Button
        HitButton                      matlab.ui.control.Button
        StandButton                    matlab.ui.control.Button
        QueueofCoordinatesSentTextAreaLabel_2  matlab.ui.control.Label
        SendCartesianCoordinateButton  matlab.ui.control.Button
        ZCoordinateEditField           matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel      matlab.ui.control.Label
        YCoordinateEditField           matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel      matlab.ui.control.Label
        XCoordinateEditField           matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel      matlab.ui.control.Label
        StartContinueButton            matlab.ui.control.Button
        EmergencyStopButton            matlab.ui.control.Button
    end

    
    properties (Access = public)        
        arduino;            % Property to store the Arduino object
        auboI5;             % Property to store the Aubo i5 object
        dobotMagician;      % Property to store the Dobot Magican object
        hand;               % Property to store the Hand (Collision Object) object
        playingCards;       % Property to store the Playing Cards object
        labAssessment2;     % Property to store the lab assessment 2 general object
        logFile;            % Variable to store the log file created
        fig;                % Variable to store the figure to plot within
        currentRobot;       % Variable to store what robot is currrently able to be controlled
        cardNum = 1;        % Variable to store what card to distribute
        player = 1;         % Variable to store what player to deal cards to
        simulationMode;     % Variable to store what mode the gui is in
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowStyle = 'alwaysontop'; 
            
            % Creating the figure showing to show the main demo
            app.fig = figure(1); % Creating figure to simulate robots
            hold on; axis([-1 1.5 -1.25 1.25 -0.76 1.5]); camlight;

            % Creating a log file for debugging
            app.logFile = log4matlab('logFile.log');
            app.logFile.SetCommandWindowLevel(app.logFile.DEBUG); % Setting the log level to debug

            % Creating the Environment
            LabAssessment2.CreateEnvironment(app.logFile);
            
            % Creating objects for various classes
            % app.arduino = Arduino();
            app.auboI5 = AuboI5(eye(4), app.logFile);
            app.dobotMagician = DMagician(eye(4) * transl(0,0.5,0), app.logFile);
            % app.hand = Hand(eye(4) * transl(0,0,1), app.logFile);
            app.playingCards = PlayingCards(eye(4) * transl(0.25, 0.5, 0.01), app.logFile);
            app.labAssessment2 = LabAssessment2();

            % Setting the default current robot to be controlled
            app.currentRobot = app.auboI5;
            app.RobotDropDownValueChanged(); % Updating the values of the sliders
        end

        % Button pushed function: SendCartesianCoordinateButton
        function SendCartesianCoordinateButtonPushed(app, event)
            % Getting the x,y,z values to create a coordiante point
            newCoordinate = [app.XCoordinateEditField.Value, ...
                app.YCoordinateEditField.Value, app.ZCoordinateEditField.Value];

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the cartesian goal of the Aubo i5
                    app.auboI5.MoveToCartesian(newCoordinate);
                    app.logFile.mlog = {app.logFile.DEBUG,'SendCartesianCoordinateButtonPushed', ...
                    'Cartesian goal sent to the Aubo i5'};

                case 'Dobot Magician'
                    % Updating the cartesian goal of the Dobot Magician
                    app.dobotMagician.MoveToCartesian(newCoordinate);
                    app.logFile.mlog = {app.logFile.DEBUG,'SendCartesianCoordinateButtonPushed', ...
                    'Cartesian goal sent to the Dobot Magician'};
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            % Updating e-stop property to opposite of its prior state
            app.eStopPressed = ~app.eStopPressed;

            % if e-stop has been pressed on, start/continue must turn false
            if app.eStopPressed
                % Updating startPressed property of GUI
                app.startPressed = false;
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop pressed, robot function paused'};
            else
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop turned off, press continue button when ready'};
            end
        end

        % Button pushed function: StartContinueButton
        function StartContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if ~app.eStopPressed
                % If e-stop hasn't been pressed, the start property can
                % be turned true - meaning robot can start operation
                app.startPressed = true;
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Robot function started/continued'};
            else
                % Logging that e-stop is on and therefore robots cannot operate
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Cannot start/continue robot operation if e-stop has been pressed'};
            end
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            % Enabling/Disabling joint 6 movement depending on if
            % Aubo i5 or Dobot Magician is selected
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Setting current robot property to the Aubo i5
                    app.currentRobot = app.auboI5;

                    % Enabling joint 6 movement
                    app.Joint6Slider.Enable = true;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-90,  90];
                    app.Joint2Slider.Limits = [5,    175];
                    app.Joint3Slider.Limits = [-175, 175];
                    app.Joint4Slider.Limits = [-350, 360];
                    app.Joint5Slider.Limits = [-175, 175];
                    app.Joint6Slider.Limits = [-360, 360];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.auboI5.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);
                    app.Joint6Slider.Value = currentJointAngles(6);

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Aubo i5 control'};

                case 'Dobot Magician'
                    % Setting current robot property to the dobot magician
                    app.currentRobot = app.dobotMagician;

                    % Disabling joint 6 movement
                    app.Joint6Slider.Enable = false;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-135, 135];
                    app.Joint2Slider.Limits = [5,    80];
                    app.Joint3Slider.Limits = [-5,   85];
                    app.Joint4Slider.Limits = [-180, 180];
                    app.Joint5Slider.Limits = [-85,  85];

                    % Setting slider values to current aubo angles
                    currentJointAngles = rad2deg(app.dobotMagician.model.getpos());
                    app.Joint1Slider.Value = currentJointAngles(1);
                    app.Joint2Slider.Value = currentJointAngles(2);
                    app.Joint3Slider.Value = currentJointAngles(3);
                    app.Joint4Slider.Value = currentJointAngles(4);
                    app.Joint5Slider.Value = currentJointAngles(5);

                    app.logFile.mlog = {app.logFile.DEBUG,'RobotDropDownValueChanged', ...
                    'Changed to Dobot Magician control'};
            end
        end

        % Button pushed function: HitButton, StandButton
        function BlackjackButtonPressed(app, event)
            % Switch case to determine what action should be taken
            switch event.Source.Tag
                % Hit Selected
                case 'Hit'
                    % Calling the hit selected function to deal cards to chosen player
                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, ...
                                app.playingCards, app.cardNum, app.player, app.logFile);
                    app.cardNum = app.cardNum + 1; % Increasing card number count

                    % If card number equals 6, stand is forced
                    if app.cardNum == 6
                        app.player = app.labAssessment2.StandSelected(app.player, app.logFile);
                        app.cardNum = 3; % Setting card num back to 3 so each player can get their 3rd card
                    end

                % Stand Selected
                case 'Stand'
                    % Calling stand selected to increase player property count
                    app.player = app.labAssessment2.StandSelected(app.player, app.logFile);
                    app.cardNum = 3; % Setting card num back to 3 so each player can get their 3rd card

                    % % If player number equals 4, deal remaining cards to the dealer
                    % if app.player == 4
                    % 
                    % end
            end

            app.logFile.mlog = {app.logFile.DEBUG,'BlackjackButtonPressed', ...
                    [event.Source.Tag, ' requested']};
        end

        % Button pushed function: StartBlackjackButton, StartTeachButton
        function DemonstrationModePressed(app, event)
            app.simulationMode = event.Source.Tag; % Setting simulation mode to new event tag
            app.logFile.mlog = {app.logFile.DEBUG,'DemonstrationModePressed', ...
                    [event.Source.Tag, ' requested']};

            % Switch case to change the lights for user indication on mode
            switch event.Source.Tag
                case "Blackjack"
                    % Changing the GUI display relative to the mode
                    app.Lamp_2.Color = [0.8 0.8 0.8];
                    app.Lamp.Color = [0 1 0];
                    app.StartContinueButton.Visible = "on";
                    app.EmergencyStopButton.Visible = "on";
                    app.HitButton.Visible = "on";
                    app.StandButton.Visible = "on";
                    app.RobotDropDown.Visible = "off";
                    app.SendCartesianCoordinateButton.Visible = "off";
                    app.Joint1Slider.Visible = "off";
                    app.Joint2Slider.Visible = "off";
                    app.Joint3Slider.Visible = "off";
                    app.Joint4Slider.Visible = "off";
                    app.Joint5Slider.Visible = "off";
                    app.Joint6Slider.Visible = "off";

                    % Dealing two cards to each player and the dealer
                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, app.playingCards, ...
                        app.cardNum, app.player, app.logFile);
                    app.cardNum = app.cardNum + 1; % Setting cardNum so next player receives card in second position
                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, app.playingCards, ...
                        app.cardNum, app.player, app.logFile);
                    app.player = app.player + 1; % Increasing player number to deal next card to new player
                    app.cardNum = 1; % Setting cardNum so next player receives card in first position

                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, app.playingCards, ...
                        app.cardNum, app.player, app.logFile);
                    app.cardNum = app.cardNum + 1; % Setting cardNum so next player receives card in second position
                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, app.playingCards, ...
                        app.cardNum, app.player, app.logFile);
                    app.player = app.player + 1; % Increasing player number to deal next card to new player
                    app.cardNum = 1; % Setting cardNum so next player receives card in first position

                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, app.playingCards, ...
                        app.cardNum, app.player, app.logFile);
                    app.cardNum = app.cardNum + 1; % Setting cardNum so next player receives card in second position
                    app.labAssessment2.HitSelected(app.auboI5, app.dobotMagician, app, app.playingCards, ...
                        app.cardNum, app.player, app.logFile);
                    app.player = 1; % Setting player number back to 1 to deal to first player again
                    app.cardNum = 3; % Setting cardNum so next cards are set in 3rd position

                case "Teach"
                    % Changing the GUI display relative to the mode
                    app.Lamp_2.Color = [0 1 0];
                    app.Lamp.Color = [0.8 0.8 0.8];
                    app.StartContinueButton.Visible = "on";
                    app.EmergencyStopButton.Visible = "off";
                    app.HitButton.Visible = "off";
                    app.StandButton.Visible = "off";
                    app.RobotDropDown.Visible = "on";
                    app.SendCartesianCoordinateButton.Visible = "on";
                    app.Joint1Slider.Visible = "on";
                    app.Joint2Slider.Visible = "on";
                    app.Joint3Slider.Visible = "on";
                    app.Joint4Slider.Visible = "on";
                    app.Joint5Slider.Visible = "on";

                    % Switch case to determine if slider 6 should be on or off
                    switch app.RobotDropDown.Value
                        case 'Aubo I5'
                            % Turning slider 6 on for Aubo i5
                            app.Joint6Slider.Visible = "on";

                        case 'Dobot Magician'
                            % Turning slider 6 on for Aubo i5
                            app.Joint6Slider.Visible = "off";
                    end
            end
        end

        % Button pushed function: EndDemonstrationButton
        function EndDemonstrationButtonPushed(app, event)
            % Closing and deleting the app objects
            close(app.fig); delete(app);
            app.logFile.mlog = {app.logFile.DEBUG,'EndDemonstrationButtonPushed', ...
                    'End demonstration requested'};
        end

        % Value changing function: Joint1Slider, Joint2Slider, 
        % ...and 4 other components
        function SliderValueChanging(app, event)
            % Updating the edit field next to the slider to display current angle
            switch event.Source.Tag
                case '1'
                    app.EditField1.Value = event.Value;
                case '2'
                    app.EditField2.Value = event.Value;
                case '3'
                    app.EditField3.Value = event.Value;
                case '4'
                    app.EditField4.Value = event.Value;
                case '5'
                    app.EditField5.Value = event.Value;
                case '6'
                    app.EditField6.Value = event.Value;
            end

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'Aubo I5'
                    % Updating the joint angle of specified joint index
                    app.auboI5.MoveJoint(event.Source.Tag, event.Value);

                    app.logFile.mlog = {app.logFile.DEBUG,'JointSliderChanging', ...
                    'Aubo i5 joint change sent'};

                case 'Dobot Magician'
                    % Updating the joint angle of specified joint index
                    app.dobotMagician.MoveJoint(event.Source.Tag, event.Value);

                    app.logFile.mlog = {app.logFile.DEBUG,'JointSliderChanging', ...
                    'Dobot Magician joint change sent'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.StartContinueButton = uibutton(app.UIFigure, 'push');
            app.StartContinueButton.ButtonPushedFcn = createCallbackFcn(app, @StartContinueButtonPushed, true);
            app.StartContinueButton.Text = 'Start/Continue';
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendCartesianCoordinateButton = uibutton(app.UIFigure, 'push');
            app.SendCartesianCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @SendCartesianCoordinateButtonPushed, true);
            app.SendCartesianCoordinateButton.Text = 'Send Cartesian Coordinate';
            app.QueueofCoordinatesSentTextAreaLabel_2 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_2.Text = {'Send End-Effector to '; 'Cartesian Coordinate:'};
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @BlackjackButtonPressed, true);
            app.StandButton.Tag = 'Stand';
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @BlackjackButtonPressed, true);
            app.HitButton.Tag = 'Hit';
            app.HitButton.Text = 'Hit';
            app.StartBlackjackButton = uibutton(app.UIFigure, 'push');
            app.StartBlackjackButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationModePressed, true);
            app.StartBlackjackButton.Tag = 'Blackjack';
            app.StartBlackjackButton.Text = 'Start Blackjack';
            app.StartTeachButton = uibutton(app.UIFigure, 'push');
            app.StartTeachButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationModePressed, true);
            app.StartTeachButton.Tag = 'Teach';
            app.StartTeachButton.Text = 'Start Teach';
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-175 175];
            app.Joint1Slider.MajorTicks = [];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Tag = '1';
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-175 175];
            app.Joint2Slider.MajorTicks = [];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Tag = '2';
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-175 175];
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Tag = '3';
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-175 175];
            app.Joint4Slider.MajorTicks = [];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Tag = '4';
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-175 175];
            app.Joint5Slider.MajorTicks = [];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Tag = '5';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.MajorTicks = [];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Tag = '6';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'Aubo I5', 'Dobot Magician'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'Aubo I5';
            app.EndDemonstrationButton = uibutton(app.UIFigure, 'push');
            app.EndDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @EndDemonstrationButtonPushed, true);
            app.EndDemonstrationButton.Text = 'End Demonstration';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField6 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end